classdef EnergyLandscape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        IbarPanel                    matlab.ui.container.Panel
        IbarrigiditySliderLabel      matlab.ui.control.Label
        IbarrigiditySlider           matlab.ui.control.Slider
        IbarcurvatureSliderLabel     matlab.ui.control.Label
        IbarcurvatureSlider          matlab.ui.control.Slider
        IbarmembraneaffinitySliderLabel  matlab.ui.control.Label
        IbarmembraneaffinitySlider   matlab.ui.control.Slider
        NbarPanel                    matlab.ui.container.Panel
        NbarcurvatureSliderLabel     matlab.ui.control.Label
        NbarcurvatureSlider          matlab.ui.control.Slider
        NbarmembraneaffinityLabel    matlab.ui.control.Label
        NbarmembraneaffinitySlider   matlab.ui.control.Slider
        NbarrigiditySliderLabel      matlab.ui.control.Label
        NbarrigiditySlider           matlab.ui.control.Slider
        NbarSwitchLabel              matlab.ui.control.Label
        NbarSwitch                   matlab.ui.control.ToggleSwitch
        IbarSwitchLabel              matlab.ui.control.Label
        IbarSwitch                   matlab.ui.control.ToggleSwitch
        membranePanel                matlab.ui.container.Panel
        membranerigiditySliderLabel  matlab.ui.control.Label
        membranerigiditySlider       matlab.ui.control.Slider
        UIAxes_Ibar                  matlab.ui.control.UIAxes
        UIAxes_Nbar                  matlab.ui.control.UIAxes
        UIAxes_main                  matlab.ui.control.UIAxes
        UIAxes_Ibar_2                matlab.ui.control.UIAxes
        UIAxes_Nbar_2                matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotFreeEnergy(app)
        end

        % Value changed function: IbarSwitch, IbarcurvatureSlider, 
        % IbarmembraneaffinitySlider, IbarrigiditySlider, 
        % NbarSwitch, NbarcurvatureSlider, 
        % NbarmembraneaffinitySlider, NbarrigiditySlider, 
        % membranerigiditySlider
        function plotFreeEnergy(app, event)

            c = -.1:.001:.1;
            a = 50;
            kM = app.membranerigiditySlider.Value;
            kI = app.IbarrigiditySlider.Value;
            kN = app.NbarrigiditySlider.Value;
            muI = app.IbarmembraneaffinitySlider.Value;
            muN = app.NbarmembraneaffinitySlider.Value;
            cI = 1/app.IbarcurvatureSlider.Value;
            cN = 1/app.NbarcurvatureSlider.Value;
            
            KdC_I = exp(muI-kI*a/2*(c-cI).^2);
            KdC_N = exp(muN-kN*a/2*(c-cN).^2);
            
            Neq = KdC_N./(1+KdC_I+KdC_N);
            Ieq = KdC_I./(1+KdC_I+KdC_N);

%             fm = .5*kM*a.*c.^2;
%             fI = .5*kI*a.*(c-cI).^2.*Ieq;
%             fN = .5*kN*a.*(c-cN).^2.*Neq;
            fm = .5*kM.*c.^2;
            fI = .5*kI.*(c-cI).^2.*Ieq;
            fN = .5*kN.*(c-cN).^2.*Neq;
            fBI = -Ieq*muI*1/a;
            fBN = -Neq*muN*1/a;
            fM = 1/a*(Ieq.*log(Ieq)+Neq.*log(Neq)+(1-Ieq-Neq).*log(1-Ieq-Neq));

            
            if app.IbarSwitch.Value == "Off"
                fI = zeros(1,length(c));
                fBI = zeros(1,length(c));
%                 fM = Neq.*log(Neq)+(1-Neq).*log(1-Neq);
                fM = 1/a*(Neq.*log(Neq)+(1-Neq).*log(1-Neq));

                Neq = KdC_N./(1+KdC_N);
            end
            
            if app.NbarSwitch.Value == "Off"
                fN = zeros(1,length(c));
                fBN = zeros(1,length(c));
                Ieq = KdC_I./(1+KdC_I);
%                 fM = Ieq.*log(Ieq)+(1-Ieq).*log(1-Ieq);
                fM = 1/a*(Ieq.*log(Ieq)+(1-Ieq).*log(1-Ieq));

            end
            
            if app.NbarSwitch.Value == "Off" && app.IbarSwitch.Value == "Off"
                fM = zeros(1,length(c));
            end
            
            feq = fm + fI + fN + fBI + fBN + fM;
            
            plot(app.UIAxes_Ibar,c,Ieq)
            app.UIAxes_Ibar.YLim = [0 1];
            app.UIAxes_Ibar.XLim = [-.1 .1]
            plot(app.UIAxes_Nbar,c,Neq)
            app.UIAxes_Nbar.YLim = [0 1];
            app.UIAxes_Nbar.XLim = [-.1 .1]
            plot(app.UIAxes_main,c,fm,c,fI,c,fN,c,fBI,c,fBN,c,fM,c,feq)
            legend(app.UIAxes_main, 'membrane','Ibar bending','Nbar bending','Ibar binding','Nbar binding','Mixing energy','total') 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.IbarPanel = uipanel(app.UIFigure);
            app.IbarPanel.Title = 'Ibar';
            app.IbarrigiditySliderLabel = uilabel(app.IbarPanel);
            app.IbarrigiditySliderLabel.Text = 'Ibar rigidity';
            app.IbarrigiditySlider = uislider(app.IbarPanel);
            app.IbarrigiditySlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.IbarrigiditySlider.Value = 35;
            app.IbarcurvatureSliderLabel = uilabel(app.IbarPanel);
            app.IbarcurvatureSliderLabel.Text = 'Ibar curvature';
            app.IbarcurvatureSlider = uislider(app.IbarPanel);
            app.IbarcurvatureSlider.Limits = [-100 -1];
            app.IbarcurvatureSlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.IbarcurvatureSlider.Value = -20;
            app.IbarmembraneaffinitySliderLabel = uilabel(app.IbarPanel);
            app.IbarmembraneaffinitySliderLabel.Text = 'Ibar membrane affinity';
            app.IbarmembraneaffinitySlider = uislider(app.IbarPanel);
            app.IbarmembraneaffinitySlider.Limits = [-5 5];
            app.IbarmembraneaffinitySlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.IbarmembraneaffinitySlider.Value = 1;
            app.NbarPanel = uipanel(app.UIFigure);
            app.NbarPanel.Title = 'Nbar';
            app.NbarcurvatureSliderLabel = uilabel(app.NbarPanel);
            app.NbarcurvatureSliderLabel.Text = 'Nbar curvature';
            app.NbarcurvatureSlider = uislider(app.NbarPanel);
            app.NbarcurvatureSlider.Limits = [1 100];
            app.NbarcurvatureSlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.NbarcurvatureSlider.Value = 20;
            app.NbarmembraneaffinityLabel = uilabel(app.NbarPanel);
            app.NbarmembraneaffinityLabel.Text = 'Nbar membrane affinity';
            app.NbarmembraneaffinitySlider = uislider(app.NbarPanel);
            app.NbarmembraneaffinitySlider.Limits = [-5 5];
            app.NbarmembraneaffinitySlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.NbarmembraneaffinitySlider.Value = 1;
            app.NbarrigiditySliderLabel = uilabel(app.NbarPanel);
            app.NbarrigiditySliderLabel.Text = 'Nbar rigidity';
            app.NbarrigiditySlider = uislider(app.NbarPanel);
            app.NbarrigiditySlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.NbarrigiditySlider.Value = 35;
            app.NbarSwitchLabel = uilabel(app.UIFigure);
            app.NbarSwitchLabel.Text = 'NbarSwitch';
            app.NbarSwitch = uiswitch(app.UIFigure, 'toggle');
            app.NbarSwitch.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.IbarSwitchLabel = uilabel(app.UIFigure);
            app.IbarSwitchLabel.Text = 'IbarSwitch';
            app.IbarSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IbarSwitch.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.IbarSwitch.Value = 'On';
            app.membranePanel = uipanel(app.UIFigure);
            app.membranePanel.Title = 'membrane';
            app.membranerigiditySliderLabel = uilabel(app.membranePanel);
            app.membranerigiditySliderLabel.Text = 'membrane rigidity';
            app.membranerigiditySlider = uislider(app.membranePanel);
            app.membranerigiditySlider.ValueChangedFcn = createCallbackFcn(app, @plotFreeEnergy, true);
            app.membranerigiditySlider.Value = 10;
            app.UIAxes_Ibar = uiaxes(app.UIFigure);
            title(app.UIAxes_Ibar, 'Ibar equilibrium saturation')
            xlabel(app.UIAxes_Ibar, 'curvature [1/nm]')
            ylabel(app.UIAxes_Ibar, 'saturation [%]')
            app.UIAxes_Nbar = uiaxes(app.UIFigure);
            title(app.UIAxes_Nbar, 'Nbar equilibrium saturation')
            xlabel(app.UIAxes_Nbar, 'curvature [1/nm]')
            ylabel(app.UIAxes_Nbar, 'saturation [%]')
            app.UIAxes_main = uiaxes(app.UIFigure);
            title(app.UIAxes_main, 'free energy')
            xlabel(app.UIAxes_main, 'curvature [1/nm]')
            ylabel(app.UIAxes_main, 'free energy per area [KbT /nm^2]')
            app.UIAxes_Ibar_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Ibar_2, 'bending energy Ibar')
            xlabel(app.UIAxes_Ibar_2, 'curvature [1/nm]')
            ylabel(app.UIAxes_Ibar_2, 'Energy [kB T]')
            app.UIAxes_Nbar_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Nbar_2, 'bending energy Nbar')
            xlabel(app.UIAxes_Nbar_2, 'curvature [1/nm]')
            ylabel(app.UIAxes_Nbar_2, 'Energy [kB T]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnergyLandscape

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end